classdef ENGR_131_GROUP47_FINAL_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlayButton               matlab.ui.control.Button
        RecordButton             matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        SamplingFrequencyLabel   matlab.ui.control.Label
        SamplingFrequencySlider  matlab.ui.control.Slider
        AudioFilesDropDownLabel  matlab.ui.control.Label
        LoadButton               matlab.ui.control.Button
        AudioFilesDropDown       matlab.ui.control.DropDown
        Lamp                     matlab.ui.control.Lamp
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_6                 matlab.ui.control.UIAxes
        UIAxes_7                 matlab.ui.control.UIAxes
        CorruptButton            matlab.ui.control.Button
        FiltersButtonGroup       matlab.ui.container.ButtonGroup
        LowPassButton            matlab.ui.control.ToggleButton
        HighPassButton           matlab.ui.control.ToggleButton
        BandStopButton           matlab.ui.control.ToggleButton
        BandPassButton           matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        Audio % Description
       
        Property2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            global recObj
            recObj = audiorecorder(44100, 16, 2); %create oject
            record(recObj); %start Recording
            app.Lamp.Color = 'r';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global recObj
            global playerObj
            stop(recObj)%stop recording
            playerObj = audioplayer(recObj)
            y=getaudiodata(recObj);
            audiowrite('audio.wav',y, 44100)
            plot(app.UIAxes,y)
            app.Lamp.Color = 'black';
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global recObj
            global playerObj
            play(playerObj)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            DataFiles = dir('*.wav');
            for i = 1:length(DataFiles)
                FileName = {DataFiles(i).name}
                app.AudioFilesDropDown.Items(i) = FileName;
            end
        end

        % Value changed function: AudioFilesDropDown
        function AudioFilesDropDownValueChanged(app, event)
            value = app.AudioFilesDropDown.Value;
            app.Audio = audioread(value);
            plot(app.UIAxes,app.Audio)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Corrupted Audio'; ''})
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Frequency')
            app.SamplingFrequencyLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyLabel.Text = {'Sampling '; 'Frequency'};
            app.SamplingFrequencySlider = uislider(app.UIFigure);
            app.AudioFilesDropDownLabel = uilabel(app.UIFigure);
            app.AudioFilesDropDownLabel.Text = 'Audio Files';
            app.AudioFilesDropDown = uidropdown(app.UIFigure);
            app.AudioFilesDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioFilesDropDownValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, {'Filtered Audio'; ''})
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Frequency')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, {'Filtered FFT'; ''})
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Frequency')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, {'Recorded Audio'; ''})
            xlabel(app.UIAxes_4, 'Time')
            ylabel(app.UIAxes_4, 'Frequency')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, {'Corrupted FFT'; ''})
            xlabel(app.UIAxes_5, 'Time')
            ylabel(app.UIAxes_5, 'Frequency')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, {'Filtered Spectroscopy'; ''})
            xlabel(app.UIAxes_6, 'Time')
            ylabel(app.UIAxes_6, 'Frequency')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, {'Corrupted Spectroscopy'; ''})
            xlabel(app.UIAxes_7, 'Time')
            ylabel(app.UIAxes_7, 'Frequency')
            app.CorruptButton = uibutton(app.UIFigure, 'push');
            app.CorruptButton.Text = 'Corrupt';
            app.FiltersButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltersButtonGroup.Title = 'Filters';
            app.LowPassButton = uitogglebutton(app.FiltersButtonGroup);
            app.LowPassButton.Text = 'Low Pass';
            app.LowPassButton.Value = true;
            app.HighPassButton = uitogglebutton(app.FiltersButtonGroup);
            app.HighPassButton.Text = {'High Pass'; ''};
            app.BandStopButton = uitogglebutton(app.FiltersButtonGroup);
            app.BandStopButton.Text = 'Band-Stop';
            app.BandPassButton = uitogglebutton(app.FiltersButtonGroup);
            app.BandPassButton.Text = 'Band-Pass';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENGR_131_GROUP47_FINAL_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end